classdef arm_path_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoubleJointArmPathPlanningUIFigure  matlab.ui.Figure
        EndingPointDropDown         matlab.ui.control.DropDown
        EndingPointDropDownLabel    matlab.ui.control.Label
        AnimateButton               matlab.ui.control.StateButton
        SpeedSlider                 matlab.ui.control.Slider
        SpeedSliderLabel            matlab.ui.control.Label
        MovementSlider              matlab.ui.control.Slider
        MovementSliderLabel         matlab.ui.control.Label
        StartingPointDropDown       matlab.ui.control.DropDown
        StartingPointDropDownLabel  matlab.ui.control.Label
        AngleAxes                   matlab.ui.control.UIAxes
        XZAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Constant)
        xyScale = 39.3701;      % inches/meter
        angleScale = 180/pi;    % deg/rad
        degreesFlag = true;
    end

    properties
        arm_kinematics;
        arm_dynamics;
        selected_path;
        origin;

        animating = false;
        h_xy_path;
        h_angle_path;
        h_distal;
        h_proximal;
        h_theta;
    end
    
    
    methods (Access = private)
        
        function draw_planned_path(app)
            theta1 = app.selected_path.theta1;
            theta2 = app.selected_path.theta2;

            [elbow.x, elbow.y, tip.x, tip.y] = app.arm_kinematics.forward_kinematics(theta1, theta2);
            
            if ~isempty(app.h_xy_path), delete(app.h_xy_path), end
            if ~isempty(app.h_angle_path), delete(app.h_angle_path), end
            
            app.h_xy_path = plot_xy_path(app.XZAxes, app.origin, elbow, tip, app.xyScale);
            app.h_angle_path = plot_angle_path(app.AngleAxes, theta1, theta2, app.angleScale);

            % interpolate trajectory to dt
            clockPeriod = 0.020;
            t = 0:clockPeriod:app.selected_path.totalTime;
            points = [app.selected_path.theta1.'; app.selected_path.theta2.'];
            [position, velocity, acceleration, jerk] = app.sample(t, app.selected_path.totalTime, points);
            [torque, voltage, current] = app.arm_dynamics.feedforward(position, velocity, acceleration);
        
%             title_str = strrep(app.selected_path.filename, '_', '\_');
%             fig = figure(1);
%             plot_state(fig, t, position, velocity, acceleration, jerk, app.degreesFlag, title_str);
%             fig = figure(2);
%             plot_dynamics(fig, t, torque, voltage, current, title_str);

        end
        
        function draw_current_state(app, t)
            [~,idx] = min(abs(t-app.selected_path.t));

            theta1 = app.selected_path.theta1(idx);
            theta2 = app.selected_path.theta2(idx);

            [elbow.x, elbow.y, tip.x, tip.y] = app.arm_kinematics.forward_kinematics(theta1, theta2);
            
            if ~isempty(app.h_distal), delete(app.h_distal), delete(app.h_proximal); delete(app.h_theta); end
            app.h_distal = line(app.XZAxes, [app.origin.x elbow.x]*app.xyScale, [app.origin.y elbow.y]*app.xyScale, 'Color', 'b', 'LineWidth', 5);
            app.h_proximal = line(app.XZAxes, [elbow.x tip.x]*app.xyScale, [elbow.y tip.y]*app.xyScale, 'Color', 'r', 'LineWidth', 5);
            app.h_theta = line(app.AngleAxes, theta1*app.angleScale, theta2*app.angleScale, 'Color','r', 'Marker', 'o');
            drawnow;
        end
        
        function change_path(app)
            start_idx = app.StartingPointDropDown.Value;
            final_idx = app.EndingPointDropDown.Value;
    
            filename = sprintf('arm_path_%d_%d.json', start_idx-1, final_idx-1);
            s = jsondecode(fileread(['..\..\src\main\deploy\paths\' filename]));

            app.selected_path.totalTime = max(s.totalTime, 0);
            app.selected_path.theta1 = s.theta1;
            app.selected_path.theta2 = s.theta2;
            app.selected_path.t = linspace(0, app.selected_path.totalTime, length(app.selected_path.theta1));
            app.selected_path.filename = filename;

            app.draw_planned_path();
            app.draw_current_state(0);

            if app.selected_path.totalTime == 0
                app.MovementSlider.Enable = false;
                app.MovementSlider.Limits = [0 100];
            else
                app.MovementSlider.Enable = true;
                app.MovementSlider.Limits = [0 app.selected_path.totalTime];
            end
        end

        function [position, velocity, acceleration, jerk] = sample(app, time, totalTime, points)
            dt = totalTime / size(points,2);
    
            prev1 = floor(time/dt) +1;
            next1 = ceil(time/dt) +1;
            next1(next1==prev1) = next1(next1==prev1)+1;
            prev2 = prev1-1 +1;
            next2 = next1+1 +1;
    
            prev1 = app.clamp(prev1, 0, length(points));
            next1 = app.clamp(next1, 0, length(points));
            prev2 = app.clamp(prev2, 0, length(points));
            next2 = app.clamp(next2, 0, length(points));
            
            if size(points,2) > 1    
                position(1,:) = interp1(linspace(0,totalTime,size(points,2)), points(1,:), time, 'linear');
                position(2,:) = interp1(linspace(0,totalTime,size(points,2)), points(2,:), time, 'linear');

                prevVelocity = (points(:,prev1) - points(:,prev2)) / dt;
                velocity = (points(:,next1) - points(:,prev1)) / dt;
                nextVelocity = (points(:,next2) - points(:,next1)) / dt;
        
                prevAcceleration = (velocity - prevVelocity) / dt;
                acceleration = (nextVelocity - velocity) / dt;
        
                jerk = (acceleration - prevAcceleration) / (2*dt);
            else
                position = points;
                velocity = zeros(size(points));
                acceleration = zeros(size(points));
                jerk = zeros(size(points));
            end

        end

        function out = clamp(app, in, low, high)
            out = min(max(in, low), high);
        end

        function [t, position, velocity, acceleration, jerk, torque, voltage, current] = get_dynamics(app, trajectory)
            % interpolate trajectory to dt
            clockPeriod = 0.020;
            t = 0:clockPeriod:trajectory.totalTime;
            [position, velocity, acceleration, jerk] = app.sample(t, trajectory.totalTime, trajectory.points);
            [torque, voltage, current] = app.arm_dynamics.feedforward(position, velocity, acceleration);
        end

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.animating = false;
            
            s = jsondecode(fileread('..\..\src\main\deploy\arm_config.json'));
            arm_length(1) = s.shoulder.length;
            arm_length(2) = s.elbow.length + s.wrist.length;
            app.origin.x = s.origin(1);
            app.origin.y = s.origin(2);

            shoulder = joint_config(s.shoulder.mass, s.shoulder.length, s.shoulder.moi, s.shoulder.cgRadius, falcon500(s.shoulder.motor.count, s.shoulder.motor.reduction));
            elbow = joint_config(s.elbow.mass, s.elbow.length, s.elbow.moi, s.elbow.cgRadius, falcon500(s.elbow.motor.count, s.elbow.motor.reduction));
            wrist = joint_config(s.wrist.mass, s.wrist.length, s.wrist.moi, s.wrist.cgRadius, falcon500(s.elbow.motor.count, s.elbow.motor.reduction));

            app.arm_kinematics = arm_kinematics(app.origin.x, app.origin.y, arm_length);
            app.arm_dynamics = arm_dynamics(shoulder, elbow, wrist);

            obstaclesJson = jsondecode(fileread('..\..\src\main\deploy\constraints.json'));
            
            presetEnum = get_preset_enum();
            preset_names = fieldnames(presetEnum);
            
            plot_xy_space(app.XZAxes, obstaclesJson, app.xyScale, 'Cartesian');
            plot_angle_space(app.AngleAxes, obstaclesJson, app.arm_kinematics, app.angleScale, 'Angle Space');
            app.h_xy_path = [];
            app.h_angle_path = [];


            for k = 1:length(preset_names)
                app.StartingPointDropDown.Items{k} = preset_names{k};
                app.StartingPointDropDown.ItemsData(k) = k;
                app.EndingPointDropDown.Items{k} = preset_names{k};
                app.EndingPointDropDown.ItemsData(k) = k;
            end
            
            app.StartingPointDropDown.Value = app.StartingPointDropDown.ItemsData(1);
            app.EndingPointDropDown.Value = app.EndingPointDropDown.ItemsData(end);
            app.StartingPointDropDownValueChanged();
            app.EndingPointDropDownValueChanged();

        end

        % Value changed function: StartingPointDropDown
        function StartingPointDropDownValueChanged(app, event)
            app.change_path();
        end

        % Value changed function: AnimateButton
        function AnimateButtonValueChanged(app, event)
            value = app.AnimateButton.Value;
            
            t_start = tic;
            t_previous = toc(t_start);
            t_elapsed = 0;
            
            % run this loop while the button is still down
            while 1
                if ~app.AnimateButton.Value
                    break
                end
                
                if ~app.animating
                    app.animating = true;
                    t_start = tic;
                    t_elapsed = 0;
                end
                
                t_current = toc(t_start);
                dt = (t_current - t_previous) * app.SpeedSlider.Value/100;
                dt = max(dt, 0);
                t_previous = t_current;
                
                t_elapsed = t_elapsed + dt;
                t_elapsed = min(t_elapsed, app.selected_path.totalTime);
                
                app.draw_current_state(t_elapsed);

                if t_elapsed >= app.selected_path.totalTime
                    app.animating = false;
                    app.AnimateButton.Value = false;
                    break
                end

                if app.animating
                    app.MovementSlider.Value = t_elapsed;
                    app.MovementSliderValueChanged(event);
                    pause(0.01);
                end
            end  

            app.animating = false;
        end

        % Value changing function: MovementSlider
        function MovementSliderValueChanging(app, event)
            t = event.Value;
            app.draw_current_state(t);
        end

        % Value changed function: EndingPointDropDown
        function EndingPointDropDownValueChanged(app, event)
            app.change_path();
        end

        % Value changed function: MovementSlider
        function MovementSliderValueChanged(app, event)
            t = app.MovementSlider.Value;
            app.draw_current_state(t);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoubleJointArmPathPlanningUIFigure = uifigure('Visible', 'off');
            app.DoubleJointArmPathPlanningUIFigure.Name = 'Double Joint Arm Path Planning';
            app.XZAxes = uiaxes(app.DoubleJointArmPathPlanningUIFigure);
            title(app.XZAxes, 'Cartesian')
            xlabel(app.XZAxes, 'Distance from Center of Robot (in)')
            ylabel(app.XZAxes, 'Height above Floor (in)')
            app.AngleAxes = uiaxes(app.DoubleJointArmPathPlanningUIFigure);
            title(app.AngleAxes, 'Angle Space')
            xlabel(app.AngleAxes, '\theta_1 (rad)')
            ylabel(app.AngleAxes, '\theta_{21} (rad)')
            app.StartingPointDropDownLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.StartingPointDropDownLabel.Text = 'Starting Point';
            app.StartingPointDropDown = uidropdown(app.DoubleJointArmPathPlanningUIFigure);
            app.StartingPointDropDown.Items = {};
            app.StartingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingPointDropDownValueChanged, true);
            app.StartingPointDropDown.Value = {};
            app.MovementSliderLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.MovementSliderLabel.Text = 'Movement';
            app.MovementSlider = uislider(app.DoubleJointArmPathPlanningUIFigure);
            app.MovementSlider.ValueChangedFcn = createCallbackFcn(app, @MovementSliderValueChanged, true);
            app.MovementSlider.ValueChangingFcn = createCallbackFcn(app, @MovementSliderValueChanging, true);
            app.SpeedSliderLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.DoubleJointArmPathPlanningUIFigure);
            app.SpeedSlider.Value = 100;
            app.AnimateButton = uibutton(app.DoubleJointArmPathPlanningUIFigure, 'state');
            app.AnimateButton.ValueChangedFcn = createCallbackFcn(app, @AnimateButtonValueChanged, true);
            app.AnimateButton.Text = 'Animate';
            app.EndingPointDropDownLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.EndingPointDropDownLabel.Text = 'Ending Point';
            app.EndingPointDropDown = uidropdown(app.DoubleJointArmPathPlanningUIFigure);
            app.EndingPointDropDown.Items = {};
            app.EndingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @EndingPointDropDownValueChanged, true);
            app.EndingPointDropDown.Value = {};
            % Show the figure after all components are created
            app.DoubleJointArmPathPlanningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arm_path_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoubleJointArmPathPlanningUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoubleJointArmPathPlanningUIFigure)
        end
    end
end